classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResultLabel            matlab.ui.control.Label
        PlotFigureButton       matlab.ui.control.Button
        DiceScoreButton        matlab.ui.control.Button
        regionLabel            matlab.ui.control.Label
        small222CheckBox       matlab.ui.control.CheckBox
        smallCheckBox          matlab.ui.control.CheckBox
        bigCheckBox            matlab.ui.control.CheckBox
        micenumTextArea        matlab.ui.control.TextArea
        micenumTextAreaLabel   matlab.ui.control.Label
        datapathTextArea       matlab.ui.control.TextArea
        datapathTextAreaLabel  matlab.ui.control.Label
        GetExpertButton        matlab.ui.control.Button
        step2Label             matlab.ui.control.Label
        step1Label             matlab.ui.control.Label
        clearmapCheckBox       matlab.ui.control.CheckBox
        simpleitkCheckBox      matlab.ui.control.CheckBox
        amapCheckBox           matlab.ui.control.CheckBox
        antsCheckBox           matlab.ui.control.CheckBox
        mba_globalCheckBox     matlab.ui.control.CheckBox
        mbaCheckBox            matlab.ui.control.CheckBox
        traditionalCheckBox    matlab.ui.control.CheckBox
        step1_antsCheckBox     matlab.ui.control.CheckBox
        step1_vmCheckBox       matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetExpertButton
        function GetExpertButtonPushed(app, event)

            app.ResultLabel.Text = ['Get Expert ing...'];
            %% 设置所有checkbox输入值
            step1_label = ["vm","ants","traditional"];
            step1_value = [app.step1_vmCheckBox.Value, app.step1_antsCheckBox.Value, app.traditionalCheckBox.Value];


            step2_label = ["mba","mba_global","ants","amap","simpleitk","clearmap"];
            step2_value = [app.mbaCheckBox.Value, app.mba_globalCheckBox.Value, app.antsCheckBox.Value, app.amapCheckBox.Value, app.simpleitkCheckBox.Value, app.clearmapCheckBox.Value];
            step2_index = find(step2_value==1);
            step2_label = step2_label(step2_index);
            disp(step2_label(1))


            region_label = ["big","small","small222"];
            region_value = [app.bigCheckBox.Value, app.smallCheckBox.Value, app.small222CheckBox.Value];
            region_index = find(region_value==1);
            region_label = region_label(region_index);


            %% 确认输入的rootpath
            rootpath = char(app.datapathTextArea.Value);
            disp(rootpath)

            %% 确认输入的鼠脑编号
            input_str_mice_nums = app.micenumTextArea.Value; 
            input_str_mice_nums = input_str_mice_nums{1};
            try
                mice_nums = str2num(input_str_mice_nums); % 解析字符串为数组
                disp(mice_nums)
                if isempty(mice_nums)
                    error('Invalid input');
                end
            catch
                disp('无效输入，请检查数组格式！');
                return;
            end
            disp(mice_nums)
            
            %% Traditional
            if step1_value(3)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f1_traditional_get_expert(rootpath,mice_nums,char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            %% vm
            if step1_value(1)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f3_PAIR_get_expert(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            %% ants-
            if step1_value(2)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f3_PAIR_get_expert(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            app.ResultLabel.Text = ['Get Expert Finished'];
        end

        % Button pushed function: DiceScoreButton
        function DiceScoreButtonPushed(app, event)

            app.ResultLabel.Text = ['Dice Score ing...'];


            %% 设置所有checkbox输入值
            step1_label = ["vm","ants","traditional"];
            step1_value = [app.step1_vmCheckBox.Value, app.step1_antsCheckBox.Value, app.traditionalCheckBox.Value];


            step2_label = ["mba","mba_global","ants","amap","simpleitk","clearmap"];
            step2_value = [app.mbaCheckBox.Value, app.mba_globalCheckBox.Value, app.antsCheckBox.Value, app.amapCheckBox.Value, app.simpleitkCheckBox.Value, app.clearmapCheckBox.Value];
            step2_index = find(step2_value==1);
            step2_label = step2_label(step2_index);
            disp(step2_label(1))


            region_label = ["big","small","small222"];
            region_value = [app.bigCheckBox.Value, app.smallCheckBox.Value, app.small222CheckBox.Value];
            region_index = find(region_value==1);
            region_label = region_label(region_index);


            %% 确认输入的rootpath
            rootpath = char(app.datapathTextArea.Value);
            disp(rootpath)

            %% 确认输入的鼠脑编号
            input_str_mice_nums = app.micenumTextArea.Value; 
            input_str_mice_nums = input_str_mice_nums{1};
            try
                mice_nums = str2num(input_str_mice_nums); % 解析字符串为数组
                disp(mice_nums)
                if isempty(mice_nums)
                    error('Invalid input');
                end
            catch
                disp('无效输入，请检查数组格式！');
                return;
            end
            disp(mice_nums)
            
            %% Traditional
            if step1_value(3)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f2_traditional_dice_score(rootpath,mice_nums,char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            %% vm
            if step1_value(1)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f4_PAIR_dice_score(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            %% ants-
            if step1_value(2)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f4_PAIR_dice_score(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end     

            app.ResultLabel.Text = ['Dice Score finished...'];
        end

        % Button pushed function: PlotFigureButton
        function PlotFigureButtonPushed(app, event)
            %% 设置所有checkbox输入值
            step1_label = ["vm","ants","traditional"];
            step1_value = [app.step1_vmCheckBox.Value, app.step1_antsCheckBox.Value, app.traditionalCheckBox.Value];


            step2_label = ["mba","mba_global","ants","amap","simpleitk","clearmap"];
            step2_value = [app.mbaCheckBox.Value, app.mba_globalCheckBox.Value, app.antsCheckBox.Value, app.amapCheckBox.Value, app.simpleitkCheckBox.Value, app.clearmapCheckBox.Value];
            step2_index = find(step2_value==1);
            step2_label = step2_label(step2_index);
            disp(step2_label(1))


            region_label = ["big","small","small222"];
            region_value = [app.bigCheckBox.Value, app.smallCheckBox.Value, app.small222CheckBox.Value];
            region_index = find(region_value==1);
            region_label = region_label(region_index);


            %% 确认输入的rootpath
            rootpath = char(app.datapathTextArea.Value);
            disp(rootpath)

            %% 确认输入的鼠脑编号
            input_str_mice_nums = app.micenumTextArea.Value; 
            input_str_mice_nums = input_str_mice_nums{1};
            try
                mice_nums = str2num(input_str_mice_nums); % 解析字符串为数组
                disp(mice_nums)
                if isempty(mice_nums)
                    error('Invalid input');
                end
            catch
                disp('无效输入，请检查数组格式！');
                return;
            end
            disp(mice_nums)
            

            %% vm
            if step1_value(1)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f5_save_dice_score(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                        f6_plt_dice_score(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                        f7_save_jsc(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                        f8_plt_jsc(rootpath,mice_nums,'vm',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end

            %% ants-
            if step1_value(2)
                for step2_num = 1:length(step2_index)
                    for region_num = 1:length(region_index)
                        f5_save_dice_score(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                        f6_plt_dice_score(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                        f7_save_jsc(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                        f8_plt_jsc(rootpath,mice_nums,'ants',char(step2_label(step2_num)), char(region_label(region_num)))
                    end
                end
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.step1_vmCheckBox = uicheckbox(app.UIFigure);
            app.step1_vmCheckBox.Text = 'vm';
            app.step1_antsCheckBox = uicheckbox(app.UIFigure);
            app.step1_antsCheckBox.Text = 'ants';
            app.traditionalCheckBox = uicheckbox(app.UIFigure);
            app.traditionalCheckBox.Text = 'traditional';
            app.mbaCheckBox = uicheckbox(app.UIFigure);
            app.mbaCheckBox.Text = 'mba';
            app.mba_globalCheckBox = uicheckbox(app.UIFigure);
            app.mba_globalCheckBox.Text = 'mba_global';
            app.antsCheckBox = uicheckbox(app.UIFigure);
            app.antsCheckBox.Text = 'ants';
            app.amapCheckBox = uicheckbox(app.UIFigure);
            app.amapCheckBox.Text = 'amap';
            app.simpleitkCheckBox = uicheckbox(app.UIFigure);
            app.simpleitkCheckBox.Text = 'simpleitk';
            app.clearmapCheckBox = uicheckbox(app.UIFigure);
            app.clearmapCheckBox.Text = 'clearmap';
            app.step1Label = uilabel(app.UIFigure);
            app.step1Label.Text = 'step1';
            app.step2Label = uilabel(app.UIFigure);
            app.step2Label.Text = 'step2';
            app.GetExpertButton = uibutton(app.UIFigure, 'push');
            app.GetExpertButton.ButtonPushedFcn = createCallbackFcn(app, @GetExpertButtonPushed, true);
            app.GetExpertButton.Text = 'Get Expert';
            app.datapathTextAreaLabel = uilabel(app.UIFigure);
            app.datapathTextAreaLabel.Text = 'datapath';
            app.datapathTextArea = uitextarea(app.UIFigure);
            app.datapathTextArea.Value = {'F:\lab\align\ly\'};
            app.micenumTextAreaLabel = uilabel(app.UIFigure);
            app.micenumTextAreaLabel.Text = 'mice num';
            app.micenumTextArea = uitextarea(app.UIFigure);
            app.bigCheckBox = uicheckbox(app.UIFigure);
            app.bigCheckBox.Text = 'big';
            app.smallCheckBox = uicheckbox(app.UIFigure);
            app.smallCheckBox.Text = 'small';
            app.small222CheckBox = uicheckbox(app.UIFigure);
            app.small222CheckBox.Text = 'small222';
            app.regionLabel = uilabel(app.UIFigure);
            app.regionLabel.Text = 'region';
            app.DiceScoreButton = uibutton(app.UIFigure, 'push');
            app.DiceScoreButton.ButtonPushedFcn = createCallbackFcn(app, @DiceScoreButtonPushed, true);
            app.DiceScoreButton.Text = 'Dice Score';
            app.PlotFigureButton = uibutton(app.UIFigure, 'push');
            app.PlotFigureButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFigureButtonPushed, true);
            app.PlotFigureButton.Text = 'Plot Figure';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end